classdef MRSflow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FlowUIFigure               matlab.ui.Figure
        DashboardPanel             matlab.ui.container.Panel
        LoadMRDButton              matlab.ui.control.Button
        Lamp1                      matlab.ui.control.Lamp
        ReconstructButton          matlab.ui.control.Button
        Lamp2                      matlab.ui.control.Lamp
        ExportDicomButton          matlab.ui.control.Button
        Lamp3                      matlab.ui.control.Lamp
        ExitButton                 matlab.ui.control.Button
        PhaseimageselectionPanel   matlab.ui.container.Panel
        Venc1cmsDropDownLabel      matlab.ui.control.Label
        Venc1DropDown              matlab.ui.control.DropDown
        Venc2cmsDropDownLabel      matlab.ui.control.Label
        Venc2DropDown              matlab.ui.control.DropDown
        ExportMatlabButton         matlab.ui.control.Button
        Lamp4                      matlab.ui.control.Lamp
        PhaseImagesPanel           matlab.ui.container.Panel
        PhaseImageFig              matlab.ui.control.UIAxes
        PDim3EditField             matlab.ui.control.NumericEditField
        PDim3Slider                matlab.ui.control.Slider
        PDim4Slider                matlab.ui.control.Slider
        PDim4EditField             matlab.ui.control.NumericEditField
        PXLabel                    matlab.ui.control.Label
        PYLabel                    matlab.ui.control.Label
        PDim3Label                 matlab.ui.control.Label
        PDim4Label                 matlab.ui.control.Label
        OrientationSpinnerLabel    matlab.ui.control.Label
        POrientationSpinner        matlab.ui.control.Spinner
        ScanparametersPanel        matlab.ui.container.Panel
        NREditFieldLabel           matlab.ui.control.Label
        NRViewField                matlab.ui.control.NumericEditField
        KMatrixViewField2          matlab.ui.control.NumericEditField
        KMatrixViewField3          matlab.ui.control.NumericEditField
        FOVViewField2              matlab.ui.control.NumericEditField
        FOVViewField3              matlab.ui.control.NumericEditField
        MXLabel                    matlab.ui.control.Label
        MYLabel                    matlab.ui.control.Label
        MZLabel                    matlab.ui.control.Label
        MatrixEditFieldLabel       matlab.ui.control.Label
        KMatrixViewField1          matlab.ui.control.NumericEditField
        FOVmmLabel                 matlab.ui.control.Label
        FOVViewField1              matlab.ui.control.NumericEditField
        TRmsLabel                  matlab.ui.control.Label
        TRViewField                matlab.ui.control.NumericEditField
        TEmsLabel                  matlab.ui.control.Label
        TEViewField                matlab.ui.control.NumericEditField
        NALabel                    matlab.ui.control.Label
        NAViewField                matlab.ui.control.NumericEditField
        FALabel                    matlab.ui.control.Label
        FAViewField                matlab.ui.control.NumericEditField
        SequenceEditFieldLabel     matlab.ui.control.Label
        SequenceViewField          matlab.ui.control.EditField
        MRDfileLabel               matlab.ui.control.Label
        MRDfileViewField           matlab.ui.control.EditField
        VenccmsLabel               matlab.ui.control.Label
        VencViewField              matlab.ui.control.EditField
        MessagesPanel              matlab.ui.container.Panel
        MessageBox                 matlab.ui.control.TextArea
        VelocityImagesPanel        matlab.ui.container.Panel
        RecoFig                    matlab.ui.control.UIAxes
        Dim3Slider                 matlab.ui.control.Slider
        Dim3EditField              matlab.ui.control.NumericEditField
        XLabel                     matlab.ui.control.Label
        YLabel                     matlab.ui.control.Label
        Dim3Label                  matlab.ui.control.Label
        LevelSlider                matlab.ui.control.Slider
        WindowLabel                matlab.ui.control.Label
        LevelLabel                 matlab.ui.control.Label
        WindowSlider               matlab.ui.control.Slider
        LevelEditField             matlab.ui.control.NumericEditField
        WindowEditField            matlab.ui.control.NumericEditField
        AutoButton                 matlab.ui.control.Button
        OrientationSpinnerLabel_2  matlab.ui.control.Label
        ImageOrientationSpinner    matlab.ui.control.Spinner
        ImageDropDownLabel         matlab.ui.control.Label
        ImageDropDown              matlab.ui.control.DropDown
        HorFig                     matlab.ui.control.UIAxes
        VerFig                     matlab.ui.control.UIAxes
        Image                      matlab.ui.control.Image
        TitleLabel                 matlab.ui.control.Label
        TulpImage                  matlab.ui.control.Image
        CreditsLabel               matlab.ui.control.Label
    end


    % ------------------------------------------------------
    %
    % 2D FLOW
    %
    %
    % Gustav Strijkers
    % Amsterdam UMC
    % g.j.strijkers@amsterdamumc.nl
    %
    %
    % Version 1.0
    %
    % - initial commit
    % - improved the phase unwrapping
    %
    % Version 1.1
    %
    %
    %
    %


    properties (Access = private)
        
        appdir                  % Application directory
        olddir                  % Starting directory
        bart_detected           % Bart toolbox detected true or false
        mrd_import_path         % MRD import path
        dicom_export_path       % Dicom export path
        matlab_export_path      % Matlab export path
        valid_file              % Valid data file true or false
        valid_reco              % Valid reconstruction true or false
        more_repetitions        % More than 1 repetition true or false
        threeDdata              % 3D data yes or no
        seqpar                  % Sequence parameters
        flipangles              % Flip angle array
        kspace                  % Raw k-space data
        phaseim                 % Phase images
        velim                   % Reconstructed velocity images
        modim                   % Reconstructed magnitude images
        difim                   % Reconstructed magnitude difference images
        images                  % Currently displayed image array
        mask                    % Segmentation mask
        image_orient            % image orientation permutation matrix
        image_orient_label      % Orientation labels in image figure
        level                   % Image intensity level
        window                  % Image intensity window
        dacvenc                 % VENC array in DAC gradient strength units
        venc                    % Array of VENC values (cm/s)
        
    end


    methods (Access = private)
        
        
        % ------------ Lights and buttons --------------------
        
        function SetLights(app,code)
            
            if ~strcmp(code{1},'Null') app.Lamp1.Color = code{1}; end
            if ~strcmp(code{2},'Null') app.Lamp2.Color = code{2}; end
            if ~strcmp(code{3},'Null') app.Lamp3.Color = code{3}; end
            if ~strcmp(code{4},'Null') app.Lamp4.Color = code{4}; end
            drawnow;
            
        end
        
        
        function result = GetLights(app)
            
            result{1} = app.Lamp1.Color;
            result{2} = app.Lamp2.Color;
            result{3} = app.Lamp3.Color;
            result{4} = app.Lamp4.Color;
            
        end
        
        
        function SetButtons(app,code)
            
            if ~strcmp(code{1},'Null') app.LoadMRDButton.Enable = code{1}; end
            if ~strcmp(code{2},'Null') app.ReconstructButton.Enable = code{2}; end
            if ~strcmp(code{3},'Null') app.ExportDicomButton.Enable = code{3}; end
            if ~strcmp(code{4},'Null') app.ExportMatlabButton.Enable = code{4}; end
            if ~strcmp(code{5},'Null') app.ExitButton.Enable = code{5}; end
            drawnow;
            
        end
        
        
        function result = GetButtons(app)
            
            result{1} = app.LoadMRDButton.Enable;
            result{2} = app.ReconstructButton.Enable;
            result{3} = app.ExportDicomButton.Enable;
            result{4} = app.ExportMatlabButton.Enable;
            result{5} = app.ExitButton.Enable;
            
        end


        % ------------ Parameters --------------------
        
        function SetParametersFcn(app)
            
            app.TextMessage('Reading parameters ....');
            
            % More than 1 repetition/flow-encoding
            if app.seqpar.EXPERIMENT_ARRAY>1
                
              app.TextMessage('Multiple repetitions detected ...');
              app.more_repetitions = true;
              
              % Caclulate the Venc values
              app.dacvenc = app.seqpar.venc_amp(1:app.seqpar.EXPERIMENT_ARRAY);
              app.venc = calcvenc2(app.dacvenc,app.seqpar);
              
              % Add the Venc values as dropdown items
              app.Venc1DropDown.Items = strsplit(num2str(round(app.venc)));
              app.Venc2DropDown.Items = strsplit(num2str(round(app.venc)));
              
              % Assign an integer number to the items list for easy selection
              app.Venc1DropDown.ItemsData = [1:1:app.seqpar.EXPERIMENT_ARRAY];
              app.Venc2DropDown.ItemsData = [1:1:app.seqpar.EXPERIMENT_ARRAY];
              
              % Preselect Venc1 and Venc2
              app.Venc1DropDown.Value = 1;
              app.Venc2DropDown.Value = 2;
              
              % Enable the selection
              app.Venc1DropDown.Enable = 'on';
              app.Venc2DropDown.Enable = 'on';
              
              
            else
                
              app.TextMessage('Single Venc ...');
              app.more_repetitions = false;
              
              app.dacvenc = app.seqpar.venc_amp(1);
              app.venc = calcvenc(app.dacvenc); 
              
              % Disable Venc selection
              app.Venc1DropDown.Enable = 'off';
              app.Venc2DropDown.Enable = 'off';
              
            end
            
            % Show some parameters in app
            app.SequenceViewField.Value = app.seqpar.PPL;
            app.TRViewField.Value = app.seqpar.tr;
            app.TEViewField.Value = app.seqpar.te;
            app.NAViewField.Value = app.seqpar.NO_AVERAGES;
            app.NRViewField.Value = app.seqpar.EXPERIMENT_ARRAY;
            app.FAViewField.Value = app.seqpar.alpha;
            app.seqpar.aratio = app.seqpar.FOVf/8;
            app.VencViewField.Value = num2str(round(app.venc));
            
            % Matrix size
            if app.seqpar.NO_VIEWS_2>1
                
                app.TextMessage('3D data ...');
                app.threeDdata = true;
                
                % 3D data
                app.MZLabel.Text = 'Z';
                
                % Matrix dimensions
                app.KMatrixViewField1.Value = app.seqpar.NO_SAMPLES;
                app.KMatrixViewField2.Value = app.seqpar.NO_VIEWS;
                app.KMatrixViewField3.Value = app.seqpar.NO_VIEWS_2;
                
                % Permute data to (X, Y, Z, Venc)
                app.kspace = permute(app.kspace,[4,2,3,1]); 
                app.kspace = flip(app.kspace,2);
                app.kspace = flip(app.kspace,3);
                
                % Field of view
                app.FOVViewField1.Value = app.seqpar.FOV;
                app.FOVViewField2.Value = app.seqpar.FOV*app.seqpar.aratio;
                app.FOVViewField3.Value = app.seqpar.SLICE_THICKNESS;
              
            else
                
                app.TextMessage('2D single- or multi-slice data ...');
                app.threeDdata = false;
                
                % 2D data
                app.MZLabel.Text = 'Slices';
                
                % Matrix dimensions
                app.KMatrixViewField1.Value = app.seqpar.NO_SAMPLES;
                app.KMatrixViewField2.Value = app.seqpar.NO_VIEWS;
                app.KMatrixViewField3.Value = app.seqpar.NO_SLICES;
                
                % Permute data to (X, Y, slices, Venc)
                app.kspace = permute(app.kspace,[3,2,4,1]); 
                
                % Field of view
                app.FOVViewField1.Value = app.seqpar.FOV;
                app.FOVViewField2.Value = app.seqpar.FOV*app.seqpar.aratio;
                app.FOVViewField3.Value = app.seqpar.SLICE_THICKNESS;
               
            end
        
            % Image orientations
            if app.threeDdata
                app.image_orient = [1 2 3 4 5 6; 2 3 1 4 5 6 ; 3 1 2 4 5 6 ; 4 1 2 3 5 6 ; 4 2 3 1 5 6 ; 4 3 1 2 5 6];
                app.image_orient_label = ["X","Y","Z","NR"; "Y","Z","X","NR"; "Z","X","Y","NR"; "NR","X","Y","Z" ; "NR","Y","Z","X" ; "NR","Z","X","Y"];
            else
                app.image_orient = [1 2 3 4 5 6; 2 3 1 4 5 6 ; 3 1 2 4 5 6 ; 4 1 2 3 5 6 ; 4 2 3 1 5 6 ; 4 3 1 2 5 6];
                app.image_orient_label = ["X","Y","Slice","NR"; "Y","Slice","X","NR"; "Slice","X","Y","NR"; "NR","X","Y","Slice" ; "NR","Y","Slice","X" ; "NR","Slice","X","Y"];
            end
            
        end % SetParametersFcn


        function SetPhaseImageLimitsFcn(app)
        
            app.TextMessage('Setting phase image parameter limits ....');
            
            % Dimension 3 selection
            im = permute(abs(app.kspace),app.image_orient(app.POrientationSpinner.Value,:));
            dim3size = size(im,3);
            app.PDim3EditField.Value = 1;
            app.PDim3Slider.Value = 1;
            
            % Set limits for dimension 3, turn of if size = 1
            if dim3size > 1
                
                app.PDim3EditField.Limits = [1,dim3size];
                app.PDim3Slider.Limits = [1,dim3size];
                app.PDim3EditField.Value = round(dim3size/2);
                app.PDim3Slider.Value = round(dim3size/2);
                app.PDim3EditField.Enable = 'on';
                app.PDim3Slider.Enable = 'on';
                
            else
                
                app.PDim3EditField.Limits = [1,2];   % needs to be [1,2] because [1,1] is not allowed
                app.PDim3Slider.Limits = [1,2];
                app.PDim3EditField.Enable = 'off';
                app.PDim3Slider.Enable = 'off';
                
            end
            
            % Dimension 4 selection
            dim4size = size(im,4);
            app.PDim4EditField.Value = 1;
            app.PDim4Slider.Value = 1;
            
            % Set limits for dimension 4, turn of if size = 1
            if dim4size > 1
                
                app.PDim4EditField.Limits = [1,dim4size];
                app.PDim4Slider.Limits = [1,dim4size];
                app.PDim4EditField.Value = round(dim4size/2);
                app.PDim4Slider.Value = round(dim4size/2);
                app.PDim4EditField.Enable = 'on';
                app.PDim4Slider.Enable = 'on';
                
            else
                
                app.PDim4EditField.Limits = [1,2]; % needs to be [1,2] because [1,1] is not allowed
                app.PDim4Slider.Limits = [1,2];
                app.PDim4EditField.Enable = 'off';
                app.PDim4Slider.Enable = 'off';
                
            end
            
            % Label the orientations
            app.PXLabel.Text = app.image_orient_label(app.POrientationSpinner.Value,1);
            app.PYLabel.Text = app.image_orient_label(app.POrientationSpinner.Value,2);
            app.PDim3Label.Text = app.image_orient_label(app.POrientationSpinner.Value,3);
            app.PDim4Label.Text = app.image_orient_label(app.POrientationSpinner.Value,4);
            
        end % SetKspaceLimitsFcn


        function SetImageLimitsFcn(app)
        
            app.TextMessage('Setting image parameter limits ....');
            
            % Dimension 3 selection
            im = permute(abs(app.images),app.image_orient(app.ImageOrientationSpinner.Value,:));
            dim3size = size(im,3);
            app.Dim3EditField.Value = 1;
            app.Dim3Slider.Value = 1;
            
            % Set limits for dimension 3, turn of if size = 1
            if dim3size > 1
                
                app.Dim3EditField.Limits = [1,dim3size];
                app.Dim3Slider.Limits = [1,dim3size];
                app.Dim3EditField.Value = round(dim3size/2);
                app.Dim3Slider.Value = round(dim3size/2);
                app.Dim3EditField.Enable = 'on';
                app.Dim3Slider.Enable = 'on';
                
            else
                
                app.Dim3EditField.Limits = [1,2]; % needs to be [1,2] because [1,1] is not allowed
                app.Dim3Slider.Limits = [1,2];
                app.Dim3EditField.Enable = 'off';
                app.Dim3Slider.Enable = 'off';
                
            end
         
            % Label the orientations
            app.XLabel.Text = app.image_orient_label(app.ImageOrientationSpinner.Value,1);
            app.YLabel.Text = app.image_orient_label(app.ImageOrientationSpinner.Value,2);
            app.Dim3Label.Text = app.image_orient_label(app.ImageOrientationSpinner.Value,3);
            
            % Intensity slider limits
            app.LevelSlider.Limits = round([min(app.images(:))-1,max(app.images(:))+1]);
            app.WindowSlider.Limits = round([1, max(app.images(:)) - min(app.images(:))+2]);
            app.LevelEditField.Limits = app.LevelSlider.Limits;
            app.WindowEditField.Limits = app.WindowSlider.Limits;
            
        end % SetImageLimitsFcn


        function RecoPhaseImagesFcn(app)
        
            % Reconstruction of the phase images
        
            if app.valid_file
               
                app.TextMessage('Reconstructing the phase images ....'); 
                
                app.phaseim = zeros(size(app.kspace));
                
                if app.threeDdata       % 3D
                    
                    for i = 1:size(app.kspace,4)        % for all Vencs
                        app.phaseim(:,:,:,i) = angle(fft3reco(squeeze(app.kspace(:,:,:,i))));
                    end
                    
                else                    % 2D multi-slice
                    
                    for i = 1:size(app.kspace,4)        % for all Vencs
                        for j = 1:size(app.kspace,3)    % for all slices
                            app.phaseim(:,:,j,i) = angle(fft2reco(squeeze(app.kspace(:,:,j,i))));
                        end
                    end
                    
                end
                
            end
            
        end % RecoPhaseImagesFcn


        function RecoVelocityImagesFcn(app)
            
            % Reconstruction of the velocity images
        
            if app.valid_file
        
                app.TextMessage('Reconstructing the velocity images ....');
                
                % Calculate phase difference images
                phasedifference = squeeze(app.phaseim(:,:,:,app.Venc1DropDown.Value)-app.phaseim(:,:,:,app.Venc2DropDown.Value));
                
                % Mask the phase differece image. 
                % This helps with phase unwrapping
                phasedifference = phasedifference.*app.mask;
                
                % Automatic 4D (x,y,z,time) phase unwrapping
                app.TextMessage('Auto 4D phase unwrapping ...');
                nrwraps = double(unwrap_4D(phasedifference));
                unwrappedimage = phasedifference + 2*pi*nrwraps;
                               
                % Calculate velocity image
                % Later has to be extended with 3 velocity directions 
                % and time
                app.velim = unwrappedimage * abs(app.venc(1)) / (2*pi);
                
            end
            
        end % RecoVelocityImagesFcn


        function RecoModImagesFcn(app)
            
            % Reconstruction of the Modulus images
        
            if app.valid_file
                
                app.TextMessage('Reconstructing the modulus images ....');
                
                app.modim = zeros(size(squeeze(app.kspace(:,:,:,1,1,1))));
                modulus = zeros(size(app.kspace));
                
                if app.threeDdata       % 3D data
                    
                    for i = 1:size(app.kspace,4)        % for all Vencs
                        modulus(:,:,:,i) = abs(fft3reco(squeeze(app.kspace(:,:,:,i))));
                    end
                    
                else                    % 2D multi-slice data
                    
                    for i = 1:size(app.kspace,4)        % for all Vencs
                        for j = 1:size(app.kspace,3)    % for all slices
                            modulus(:,:,j,i) = abs(fft2reco(squeeze(app.kspace(:,:,j,i))));
                        end
                    end
                    
                end
                
                % Modulus image, sum of all modulus images with different Vencs
                app.modim = sum(modulus,4);
                
            end
            
        end % RecoModImagesFcn


        function RecoComplexDiffImagesFcn(app)
            
            % Reconstruction of the complex difference modulus image
            % (flowing spins only)
        
            if app.valid_file
                
                app.TextMessage('Reconstructing the complex difference modulus images ....');
                
                app.difim = zeros(size(squeeze(app.kspace(:,:,:,1,1,1))));
                compleximage = zeros(size(app.kspace));
                
                if app.threeDdata   % 3D data
                    
                    for i = 1:size(app.kspace,4)        % for all Vencs 
                        compleximage(:,:,:,i) = fft3reco(squeeze(app.kspace(:,:,:,i)));
                    end
                         
                else                % 2D multi-slice data
                    
                    for i = 1:size(app.kspace,4)        % for all Vencs
                        for j = 1:size(app.kspace,3)    % for all slices
                            compleximage(:,:,j,i) = fft2reco(squeeze(app.kspace(:,:,j,i)));
                        end
                    end
                    
                end
                
                % Difference image, absolute value of the complex
                % difference of 2 velocity-encoded images
                app.difim = abs(compleximage(:,:,:,app.Venc1DropDown.Value) - compleximage(:,:,:,app.Venc2DropDown.Value));
                
            end
            
        end % RecoComplexDiffImagesFcn


        function AutoThresholdFcn(app)
        
                % Auto threshold the images
                
                app.TextMessage('Auto thresholding ....');
                
                % Determine optimal threshold Otsu method
                optimal_threshold = graythresh(mat2gray(app.modim)) * max(app.modim(:));
                
                % Create a binary mask
                app.mask = zeros(size(app.modim));
                app.mask(app.modim>optimal_threshold) = 1;
                
        end % AutoThresholdFcn


        % ------------ Figures --------------------
        
        
        function result = CalcAspectratioFnc(app,dim1label,dim2label,sizex,sizey)
            
            % Calculates the aspect ratio that needs to be specified with
            % imshow, taken into account the FOV dimensions as well as
            % matrix size
        
            if strcmp(dim1label,'X') L1 = app.FOVViewField1.Value; end
            if strcmp(dim1label,'Y') L1 = app.FOVViewField2.Value; end
            if strcmp(dim1label,'Z') L1 = app.FOVViewField3.Value; end
            if strcmp(dim1label,'NR') L1 = mean([app.FOVViewField1.Value,app.FOVViewField2.Value]); end
            if strcmp(dim1label,'Slice') L1 = app.FOVViewField3.Value*app.KMatrixViewField3.Value; end
            
            if strcmp(dim2label,'X') L2 = app.FOVViewField1.Value; end
            if strcmp(dim2label,'Y') L2 = app.FOVViewField2.Value; end
            if strcmp(dim2label,'Z') L2 = app.FOVViewField3.Value; end
            if strcmp(dim2label,'NR') L2 = mean([app.FOVViewField1.Value,app.FOVViewField2.Value]); end
            if strcmp(dim2label,'Slice') L2 = app.FOVViewField3.Value*app.KMatrixViewField3.Value; end
            
            result = (L1/L2)*(sizey/sizex);
            
        end % Aspectratio
                
        
        function AutoIntensity(app)
            
            if app.valid_reco
                
                % Intensity slider limits
                app.LevelSlider.Limits = round([min(app.images(:))-1,max(app.images(:))+1]);
                app.WindowSlider.Limits = round([1, max(app.images(:)) - min(app.images(:))+2]);
                app.LevelEditField.Limits = app.LevelSlider.Limits;
                app.WindowEditField.Limits = app.WindowSlider.Limits;
                
                % Starting level and window, covers the full range of intensities
                app.level = min(app.images(:))+(max(app.images(:))-min(app.images(:)))/2;
                app.window = (max(app.images(:))-min(app.images(:)));
                
                % Check whether level and window are within limits (due to rounding off they could go out-of-bounds)
                if app.level < app.LevelSlider.Limits(1) app.level = app.LevelSlider.Limits(1); end
                if app.level > app.LevelSlider.Limits(2) app.level = app.LevelSlider.Limits(2); end
                if app.window < app.WindowSlider.Limits(1) app.window = app.WindowSlider.Limits(1); end
                if app.window > app.WindowSlider.Limits(2) app.window = app.WindowSlider.Limits(2); end
                
                % Set the level and window values and sliders in the app
                app.LevelSlider.Value = app.level;
                app.WindowSlider.Value = app.window;
                app.LevelEditField.Value = round(app.level);
                app.WindowEditField.Value = round(app.window);
                
            end
        
        end % AutoIntensity
                
        
        function PlotPhaseImages(app)
            
            cla(app.PhaseImageFig);
            hold(app.PhaseImageFig,'on');
            
            if app.valid_file
                
                % Color map
                cmap = gray; 
                
                % Select the data
                im = app.phaseim;
                
                % Select which slice to show
                im = permute(im,app.image_orient(app.POrientationSpinner.Value,:));
                im = squeeze(im(:,:,app.PDim3EditField.Value,app.PDim4EditField.Value,1,1));
                
                % Calculate aspect ratio
                aspectratio = CalcAspectratioFnc(app,app.PXLabel.Text,app.PYLabel.Text,size(im,1),size(im,2));
                                
                % Plot the image in the phase images panel
                app.PhaseImageFig.Position = [34,39,325,325];
                imshow(rot90(im),[-pi pi],'ColorMap',cmap,'InitialMagnification','fit','Parent',app.PhaseImageFig);
                xlim(app.PhaseImageFig,[0, size(im,1) + 1]);
                ylim(app.PhaseImageFig,[0, size(im,2) + 1]);
                daspect(app.PhaseImageFig,[1 aspectratio 1]);
                
            end
             
            hold(app.PhaseImageFig,'off');
            drawnow;
            
        end % PlotPhaseImages
        
            
        function PlotImageDataFig(app)
            
            cla(app.RecoFig);
            hold(app.RecoFig,'on');
            
            if app.valid_reco
                
                % Color map
                cmap = gray; 
                
                % Select the data
                im = app.images;
                
                % Select which slice to show
                im = permute(im,app.image_orient(app.ImageOrientationSpinner.Value,:));
                im = squeeze(im(:,:,app.Dim3EditField.Value,1,1,1));
                
                % Calculate aspect ratio
                aspectratio = CalcAspectratioFnc(app,app.XLabel.Text,app.YLabel.Text,size(im,1),size(im,2));
                
                % Plot the image in the velocity images panel
                app.RecoFig.Position = [43,234,325,325];
                imshow(rot90(im),[app.level-app.window/2 app.level+app.window/2],'ColorMap',cmap,'InitialMagnification','fit','Parent',app.RecoFig);
                xlim(app.RecoFig,[0, size(im,1) + 1]);
                ylim(app.RecoFig,[0, size(im,2) + 1]);
                daspect(app.RecoFig,[1 aspectratio 1]);
                
            end
             
            hold(app.RecoFig,'off');
            drawnow;
            
        end % PlotImageDataFig


    end


    % ------------ Public functions --------------------
    
    methods (Access = public)
        
        function TextMessage(app, message)
            
            % Text messages in the app
            app.MessageBox.Value = [message ; app.MessageBox.Value];
            drawnow;
            
        end % TextMessage
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Empty file import and export paths
            app.mrd_import_path = '';
            app.dicom_export_path = '';
            app.matlab_export_path = '';
            
            % Add subfolders to search path
            if (~isdeployed)
                app.olddir = pwd;
                info = dir(which('MRSflow.mlapp'));
                app.appdir = info.folder;
                cd(app.appdir);
                addpath(genpath(pwd));
            end
        
            % Load the images, do not change, required for user interaction in figure !
            app.TulpImage.ImageSource = [app.appdir,filesep,'Images',filesep,'AmsterdamUMC.gif'];
            app.Image.ImageSource = [app.appdir,filesep,'images',filesep,'square.gif'];
            
            % Suppress unneccesary warnings
            warning off;
            
            % Initialize buttons
            app.SetLights({'Red','Red','Red','Red'});
            app.SetButtons({'On','Off','Off','Off','On'});
            
        end

        % Button pushed function: LoadMRDButton
        function LoadMRDButtonPushed(app, event)
            
            % Set buttons
            app.SetLights({'Yellow','Null','Null','Null'});
            
            % Select 1 MRD file which contains PC-encoded data
            app.FlowUIFigure.Visible = 'off';
            [mrdfile,app.mrd_import_path] = uigetfile(strcat(app.mrd_import_path,filesep,'*.MRD'));
            app.FlowUIFigure.Visible = 'on';
            
            % Initial settings
            app.valid_file = false;
            app.valid_reco = false;
            app.threeDdata = false;
            app.more_repetitions = false;
            
            if ischar(mrdfile)
                
                % MRD file selected
                app.MRDfileViewField.Value = mrdfile;
                mrdfile = [app.mrd_import_path,mrdfile];
                
                % Load the data
                app.TextMessage('Reading MRD file ...');
                [app.kspace,~,app.seqpar] = Get_mrd_3D4(mrdfile,'seq','cen');
                app.valid_file = true;
                
                % Determine the sequence file
                if ismac
                    app.seqpar.PPL = replace(app.seqpar.PPL,'\','/');
                end
                [~,name,ext] = fileparts(app.seqpar.PPL);
                app.seqpar.PPL = strcat(name,ext);
                
            end
            
            % Report on valid/invalid file selection
            if app.valid_file
                
                % Set other parameters
                app.SetParametersFcn;
                
                % Reconstruct into phase images
                app.RecoPhaseImagesFcn;
                
                % Plot the phase images
                app.PlotPhaseImages;
                app.SetPhaseImageLimitsFcn;
                
                app.TextMessage('Data import ready ...');
                app.TextMessage(strcat('Size of k-space =',{' '},num2str(size(app.kspace))));
                
                % Set buttons
                app.SetLights({'Green','Red','Red','Red'});
                app.SetButtons({'On','On','Off','Off','On'});
           
            else                
                
                % Not a valid data file 
                uialert(app.FlowUIFigure,'Please select a valid data file','Error');
                
                % Set the buttons for invalid data file 
                app.SetLights({'Red','Red','Red','Red'});
                app.SetButtons({'On','Off','Off','Off','On'});
                
            end
            
        end

        % Value changed function: POrientationSpinner
        function POrientationSpinnerValueChanged(app, event)
            
            % Phase image orientation changed
        
            if app.valid_file
                
                app.SetPhaseImageLimitsFcn;
                app.PlotPhaseImages;
                
            end
            
        end

        % Value changed function: PDim4EditField
        function PDim4EditFieldValueChanged(app, event)
            
            % 4th phase image dimension value changed
        
            if app.valid_file
                
                app.PDim4Slider.Value = app.PDim4EditField.Value;
                app.PlotPhaseImages;
                
            end
        
        end

        % Value changed function: PDim4Slider
        function PDim4SliderValueChanged(app, event)
            
            % 4th phase image dimension slider value changed
        
            if app.valid_file
                
                app.PDim4EditField.Value = round(app.PDim4Slider.Value);
                app.PlotPhaseImages;
                
            end
            
        end

        % Value changed function: PDim3EditField
        function PDim3EditFieldValueChanged(app, event)
            
            % 3rd phase image dimension value changed
        
            if app.valid_file
                
                app.PDim3Slider.Value = app.PDim3EditField.Value;
                app.PlotPhaseImages;
                
            end
            
        end

        % Value changed function: PDim3Slider
        function PDim3SliderValueChanged(app, event)
            
            % 3rd phase image dimension slider value changed
            
            if app.valid_file
                
                app.PDim3EditField.Value = round(app.PDim3Slider.Value);
                app.PlotPhaseImages;
                
            end
            
        end

        % Button pushed function: ReconstructButton
        function ReconstructButtonPushed(app, event)
            
            app.valid_reco = false;
            
            if app.valid_file
                
                app.TextMessage('Reconstructing the velocity data ...');
                
                % Set buttons
                app.SetLights({'Null','Yellow','Null','Null'});
                                
                % Calculate modulus images
                app.RecoModImagesFcn;
                
                % Threshold
                app.AutoThresholdFcn;
                
                % Reconstruc velocity images
                app.RecoVelocityImagesFcn;
                
                % Reconstruct complex difference modulus image 
                app.RecoComplexDiffImagesFcn;
                
                % Flag valid reco true
                app.valid_reco = true;
               
            end
            
            if app.valid_reco
                
                % Show initial images
                app.images = app.velim.*app.mask;
                app.ImageDropDown.Value = '3';
                app.SetImageLimitsFcn;
                app.AutoIntensity;
                app.PlotImageDataFig;
                
                % Set buttons
                app.SetLights({'Green','Green','Red','Red'});
                app.SetButtons({'On','On','Off','On','On'});
                 
            end
            
        end

        % Value changed function: Dim3EditField
        function Dim3EditFieldValueChanged(app, event)
            
            % 3rd image dimensions edit value changed
        
            if app.valid_reco
                
                app.Dim3Slider.Value = app.Dim3EditField.Value;
                app.PlotImageDataFig;
                
            end
            
        end

        % Value changed function: Dim3Slider
        function Dim3SliderValueChanged(app, event)
            
            % 3rd image dimensions slider value changed
        
            if app.valid_reco
                
                app.Dim3EditField.Value = round(app.Dim3Slider.Value);
                app.PlotImageDataFig;
                
            end
            
        end

        % Value changed function: ImageDropDown
        function ImageDropDownValueChanged(app, event)
            
            % Controls which image is shown (velocity, magnitude, difference)
        
            if app.valid_reco
                
                switch app.ImageDropDown.Value
                    
                    case '1'
                        
                        app.TextMessage('3D velocity not implemented yet ...');
                        app.ImageDropDown.Value = '3';
                        app.images = app.velim.*app.mask;
                        app.SetImageLimitsFcn;
                        app.AutoIntensity;
                        app.PlotImageDataFig;
                        
                    case '2'
                        
                        app.TextMessage('3D velocity not implemented yet ...');
                        app.ImageDropDown.Value = '3';
                        app.images = app.velim.*app.mask;
                        app.SetImageLimitsFcn;
                        app.AutoIntensity;
                        app.PlotImageDataFig;
                        
                    case '3'
                        
                        app.images = app.velim.*app.mask;
                        app.SetImageLimitsFcn;
                        app.AutoIntensity;
                        app.PlotImageDataFig;
                        
                    case '4'
                        
                        app.TextMessage('3D velocity not implemented yet ...');
                        app.ImageDropDown.Value = '3';
                        app.images = app.velim.*app.mask;
                        app.SetImageLimitsFcn;
                        app.AutoIntensity;
                        app.PlotImageDataFig;
                        
                    case '5'
                        
                        app.images = app.modim.*app.mask;
                        app.SetImageLimitsFcn;
                        app.AutoIntensity;
                        app.PlotImageDataFig;
                        
                    case '6'
                        
                        app.images = app.difim.*app.mask;
                        app.SetImageLimitsFcn;
                        app.AutoIntensity;
                        app.PlotImageDataFig;
                        
                end
                
            end
            
        end

        % Value changed function: ImageOrientationSpinner
        function ImageOrientationSpinnerValueChanged(app, event)
            
            % Image orientation changed
        
            if app.valid_reco
                
                app.SetImageLimitsFcn;
                app.PlotImageDataFig;
                
            end
            
        end

        % Value changed function: LevelSlider
        function LevelSliderValueChanged(app, event)
            
            % Level slider value changed
        
            if app.valid_reco
                
                app.LevelEditField.Value = round(app.LevelSlider.Value);
                app.level = app.LevelEditField.Value;
                app.PlotImageDataFig;
                
            end
            
        end

        % Value changed function: LevelEditField
        function LevelEditFieldValueChanged(app, event)
             
            % Level value changed
        
            if app.valid_reco
                
                app.LevelSlider.Value = app.LevelEditField.Value;
                app.level = app.LevelEditField.Value;
                app.PlotImageDataFig;
                
             end
            
        end

        % Value changed function: WindowSlider
        function WindowSliderValueChanged(app, event)
            
            % Window slider value changed
        
            if app.valid_reco
                
                app.WindowEditField.Value = round(app.WindowSlider.Value);
                app.window = app.WindowEditField.Value;
                app.PlotImageDataFig;
                
            end
            
        end

        % Value changed function: WindowEditField
        function WindowEditFieldValueChanged(app, event)
             
            % Window value changed
        
            if app.valid_reco
                
                app.WindowSlider.Value = app.WindowEditField.Value;
                app.window = app.WindowEditField.Value;
                app.PlotImageDataFig;
                
             end
            
        end

        % Button pushed function: AutoButton
        function AutoButtonPushed(app, event)
            
            % Adjust level and window settings automatically
        
            if app.valid_reco
                
                app.AutoIntensity;
                app.PlotImageDataFig;
                
            end
        
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            
            if app.valid_reco
                
                % Get the Position of the GUI and the position of the mouse click
                set(0,'units','pixels');
                guiposition = get(app.FlowUIFigure,'Position');
                cursorposition = get(0,'PointerLocation');
                
                % Position of the cursor click in the image window
                imagepos = [865,454]; % position of bottom-left corner of image in the app, relative to the app window  (865,454)
                Xpos = cursorposition(1)-guiposition(1)-imagepos(1);
                Ypos = cursorposition(2)-guiposition(2)-imagepos(2);
                
                % Image matrix size
                xsize = size(app.images,app.image_orient(app.ImageOrientationSpinner.Value,1));
                ysize = size(app.images,app.image_orient(app.ImageOrientationSpinner.Value,2));
                                
                % Image aspect ratio
                aratio = CalcAspectratioFnc(app,app.XLabel.Text,app.YLabel.Text,xsize,ysize);
                                
                % Correct for non-square image
                imsize = 310;
                if aratio <= 1
                    xcor = (Xpos/imsize) * xsize;
                    ycor = (Ypos/imsize) * ysize * aratio - (0.5 * (aratio-1) * ysize);
                else
                    xcor = (Xpos/imsize) * xsize * (1/aratio) - (0.5 * ((1/aratio)-1) * xsize);     
                    ycor = (Ypos/imsize) * ysize;
                end
                
                % Round to nearest integer
                xcor = round(xcor);
                ycor = round(ycor);
                
                % Keep the values within acquisition matrix boundaries
                if xcor < 1 xcor = 1; end
                if ycor < 1 ycor = 1; end
                if xcor > xsize xcor = xsize; end
                if ycor > ysize ycor = ysize; end
                
                % Select the image data in the selected pixel
                im = permute(app.images,app.image_orient(app.ImageOrientationSpinner.Value,:));
                im = squeeze(im(:,:,app.Dim3EditField.Value,1,1,1));
                hor = squeeze(im(:,ycor)); % horizontal profile
                ver = squeeze(im(xcor,:)); % vertical profile
                
                % Plot the horizontal profile
                cla(app.HorFig);
                hold(app.HorFig,'on');
                plot(app.HorFig,hor,'LineWidth',2);
                xlim(app.HorFig,[0, xsize+1]);
                hold(app.HorFig,'off');
                
                % Plot the vertical profile
                cla(app.VerFig);
                hold(app.VerFig,'on');
                plot(app.VerFig,ver,'LineWidth',2);
                xlim(app.VerFig,[0, ysize+1]);
                hold(app.VerFig,'off')
                
                % draw a cross-bar on the reco figure
                app.PlotImageDataFig;
                hold(app.RecoFig,'on');
                xline(app.RecoFig,xcor,'--r');            % red dashed lines
                yline(app.RecoFig,ysize-ycor+1,'--r');    
                hold(app.RecoFig,'off');
                
            end
        
        end

        % Button pushed function: ExportDicomButton
        function ExportDicomButtonPushed(app, event)
            
            % Export DICOM
        
            if app.valid_reco
                
                % Set buttons
                app.SetLights({'Null','Null','Yellow','Null'});
                
                % Ask for directory
                app.FlowUIFigure.Visible = 'off';
                app.dicom_export_path = uigetdir(app.dicom_export_path,'DICOM export path');
                app.FlowUIFigure.Visible = 'on';
                drawnow;
                
                % Check if directory exists
                ready = true;
                if ~ischar(app.dicom_export_path)   % User cancel
                    ready = false;
                    app.dicom_export_path = '';
                else
                    [~, Attrib] = fileattrib(app.dicom_export_path);
                    if ~any(Attrib.UserWrite)   % Check if directory is writable
                        ready = false;
                    end
                end
                
                % If directory is valid and writable
                if ready
                    
                    % Some parameters
                    if app.threeDdata
                        app.seqpar.dimensionality = '3D';
                    else
                        app.seqpar.dimensionality = '2D';
                    end
                    
                    % Dicom export
                    % NOT IMPLEMENTED YET
                    
                    % Reset buttons
                    app.SetLights({'Null','Null','Green','Null'});
                    app.SetButtons({'On','On','On','On','On'});
                    
                else
                    
                    % Alert
                    uialert(app.FlowUIFigure,'Not a valid directory','Error');
                    
                    % Reset buttons
                    app.SetLights({'Null','Null','Red','Null'});
                    app.SetButtons({'On','On','On','On','On'});
                    
                end
                
            end
        
        end

        % Button pushed function: ExportMatlabButton
        function ExportMatlabButtonPushed(app, event)
            
        % Export to Matlab files
        
            if app.valid_reco
                
                % Set buttons
                app.SetLights({'Null','Null','Null','Yellow'});
                
                % Ask for directory
                app.FlowUIFigure.Visible = 'off';
                app.matlab_export_path = uigetdir(app.matlab_export_path,'MATLAB export path');
                app.FlowUIFigure.Visible = 'on';
                drawnow;
                
                % Check if directory exists
                ready = true;
                if ~ischar(app.matlab_export_path)   % user cancel
                    ready = false;
                    app.matlab_export_path = '';
                else
                    [~, Attrib] = fileattrib(app.matlab_export_path);
                    if ~any(Attrib.UserWrite)   % check if directory is writable
                        ready = false;
                    end
                end
                
                % If directory is valid and writable
                if ready
                    
                    app.TextMessage('Exporting to Matlab files ...');
                    
                    % Filename tag
                    tag = app.MRDfileViewField.Value(1:end-4);
                    
                    % Export
                    export_matlab_flow(app.matlab_export_path,app.velim,app.modim,app.difim,app.mask,tag);
                    
                    % Reset buttons
                    app.SetLights({'Null','Null','Null','Green'});
                    app.SetButtons({'On','On','Null','On','On'});
                    
                else
                    
                    % Alert
                    uialert(app.FlowUIFigure,'Not a valid directory','Error');
                    
                    % Reset buttons
                    app.SetLights({'Null','Null','Null','Red'});
                    app.SetButtons({'On','On','Null','On','On'});
                    
                end
                
            end
        
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            
            % Remove subfolders from search path
            if (~isdeployed)
                info = dir(which('MRSflow.mlapp'));
                app.appdir = info.folder;
                rmpath(genpath(app.appdir));
                cd(app.olddir);
            end
            
            % Exit
            app.delete;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FlowUIFigure = uifigure('Visible', 'off');
            app.DashboardPanel = uipanel(app.FlowUIFigure);
            app.DashboardPanel.Title = 'Dashboard';
            app.LoadMRDButton = uibutton(app.DashboardPanel, 'push');
            app.LoadMRDButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMRDButtonPushed, true);
            app.LoadMRDButton.Text = 'Load MRD';
            app.Lamp1 = uilamp(app.DashboardPanel);
            app.ReconstructButton = uibutton(app.DashboardPanel, 'push');
            app.ReconstructButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructButtonPushed, true);
            app.ReconstructButton.Text = 'Reconstruct';
            app.Lamp2 = uilamp(app.DashboardPanel);
            app.ExportDicomButton = uibutton(app.DashboardPanel, 'push');
            app.ExportDicomButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDicomButtonPushed, true);
            app.ExportDicomButton.Text = 'Export Dicom';
            app.Lamp3 = uilamp(app.DashboardPanel);
            app.ExitButton = uibutton(app.DashboardPanel, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.PhaseimageselectionPanel = uipanel(app.DashboardPanel);
            app.PhaseimageselectionPanel.Title = 'Phase image selection';
            app.Venc1cmsDropDownLabel = uilabel(app.PhaseimageselectionPanel);
            app.Venc1cmsDropDownLabel.Text = 'Venc 1 (cm/s)';
            app.Venc1DropDown = uidropdown(app.PhaseimageselectionPanel);
            app.Venc2cmsDropDownLabel = uilabel(app.PhaseimageselectionPanel);
            app.Venc2cmsDropDownLabel.Text = 'Venc 2 (cm/s)';
            app.Venc2DropDown = uidropdown(app.PhaseimageselectionPanel);
            app.ExportMatlabButton = uibutton(app.DashboardPanel, 'push');
            app.ExportMatlabButton.ButtonPushedFcn = createCallbackFcn(app, @ExportMatlabButtonPushed, true);
            app.ExportMatlabButton.Text = 'Export Matlab';
            app.Lamp4 = uilamp(app.DashboardPanel);
            app.PhaseImagesPanel = uipanel(app.FlowUIFigure);
            app.PhaseImagesPanel.Title = 'Phase Images';
            app.PhaseImageFig = uiaxes(app.PhaseImagesPanel);
            title(app.PhaseImageFig, '')
            xlabel(app.PhaseImageFig, '')
            ylabel(app.PhaseImageFig, '')
            app.PhaseImageFig.XTick = [];
            app.PhaseImageFig.YTick = [];
            app.PDim3EditField = uieditfield(app.PhaseImagesPanel, 'numeric');
            app.PDim3EditField.Limits = [1 Inf];
            app.PDim3EditField.ValueDisplayFormat = '%.0f';
            app.PDim3EditField.ValueChangedFcn = createCallbackFcn(app, @PDim3EditFieldValueChanged, true);
            app.PDim3EditField.Value = 1;
            app.PDim3Slider = uislider(app.PhaseImagesPanel);
            app.PDim3Slider.Limits = [1 200];
            app.PDim3Slider.MajorTicks = [];
            app.PDim3Slider.ValueChangedFcn = createCallbackFcn(app, @PDim3SliderValueChanged, true);
            app.PDim3Slider.MinorTicks = [];
            app.PDim3Slider.Value = 1;
            app.PDim4Slider = uislider(app.PhaseImagesPanel);
            app.PDim4Slider.Limits = [1 200];
            app.PDim4Slider.MajorTicks = [];
            app.PDim4Slider.ValueChangedFcn = createCallbackFcn(app, @PDim4SliderValueChanged, true);
            app.PDim4Slider.MinorTicks = [];
            app.PDim4Slider.Value = 1;
            app.PDim4EditField = uieditfield(app.PhaseImagesPanel, 'numeric');
            app.PDim4EditField.Limits = [1 Inf];
            app.PDim4EditField.ValueDisplayFormat = '%.0f';
            app.PDim4EditField.ValueChangedFcn = createCallbackFcn(app, @PDim4EditFieldValueChanged, true);
            app.PDim4EditField.Value = 1;
            app.PXLabel = uilabel(app.PhaseImagesPanel);
            app.PXLabel.Text = 'X';
            app.PYLabel = uilabel(app.PhaseImagesPanel);
            app.PYLabel.Text = 'Y';
            app.PDim3Label = uilabel(app.PhaseImagesPanel);
            app.PDim3Label.Text = 'Slice';
            app.PDim4Label = uilabel(app.PhaseImagesPanel);
            app.PDim4Label.Text = '             NR';
            app.OrientationSpinnerLabel = uilabel(app.PhaseImagesPanel);
            app.OrientationSpinnerLabel.Text = 'Orientation';
            app.POrientationSpinner = uispinner(app.PhaseImagesPanel);
            app.POrientationSpinner.Limits = [1 6];
            app.POrientationSpinner.ValueChangedFcn = createCallbackFcn(app, @POrientationSpinnerValueChanged, true);
            app.POrientationSpinner.Value = 1;
            app.ScanparametersPanel = uipanel(app.FlowUIFigure);
            app.ScanparametersPanel.Title = 'Scan parameters';
            app.NREditFieldLabel = uilabel(app.ScanparametersPanel);
            app.NREditFieldLabel.Text = 'NR';
            app.NRViewField = uieditfield(app.ScanparametersPanel, 'numeric');
            app.NRViewField.Editable = 'off';
            app.KMatrixViewField2 = uieditfield(app.ScanparametersPanel, 'numeric');
            app.KMatrixViewField2.Limits = [1 Inf];
            app.KMatrixViewField2.ValueDisplayFormat = '%.0f';
            app.KMatrixViewField2.Editable = 'off';
            app.KMatrixViewField2.Value = 1;
            app.KMatrixViewField3 = uieditfield(app.ScanparametersPanel, 'numeric');
            app.KMatrixViewField3.Limits = [1 Inf];
            app.KMatrixViewField3.ValueDisplayFormat = '%.0f';
            app.KMatrixViewField3.Editable = 'off';
            app.KMatrixViewField3.Value = 1;
            app.FOVViewField2 = uieditfield(app.ScanparametersPanel, 'numeric');
            app.FOVViewField2.Limits = [0 Inf];
            app.FOVViewField2.ValueDisplayFormat = '%.1f';
            app.FOVViewField2.Editable = 'off';
            app.FOVViewField3 = uieditfield(app.ScanparametersPanel, 'numeric');
            app.FOVViewField3.Limits = [0 Inf];
            app.FOVViewField3.ValueDisplayFormat = '%.1f';
            app.FOVViewField3.Editable = 'off';
            app.MXLabel = uilabel(app.ScanparametersPanel);
            app.MXLabel.Text = 'Kx';
            app.MYLabel = uilabel(app.ScanparametersPanel);
            app.MYLabel.Text = 'Ky';
            app.MZLabel = uilabel(app.ScanparametersPanel);
            app.MZLabel.Text = 'Kz';
            app.MatrixEditFieldLabel = uilabel(app.ScanparametersPanel);
            app.MatrixEditFieldLabel.Text = 'Matrix';
            app.KMatrixViewField1 = uieditfield(app.ScanparametersPanel, 'numeric');
            app.KMatrixViewField1.Limits = [1 Inf];
            app.KMatrixViewField1.ValueDisplayFormat = '%.0f';
            app.KMatrixViewField1.Editable = 'off';
            app.KMatrixViewField1.Value = 1;
            app.FOVmmLabel = uilabel(app.ScanparametersPanel);
            app.FOVmmLabel.Text = 'FOV (mm)';
            app.FOVViewField1 = uieditfield(app.ScanparametersPanel, 'numeric');
            app.FOVViewField1.ValueDisplayFormat = '%.1f';
            app.FOVViewField1.Editable = 'off';
            app.TRmsLabel = uilabel(app.ScanparametersPanel);
            app.TRmsLabel.Text = 'TR (ms)';
            app.TRViewField = uieditfield(app.ScanparametersPanel, 'numeric');
            app.TRViewField.Editable = 'off';
            app.TEmsLabel = uilabel(app.ScanparametersPanel);
            app.TEmsLabel.Text = 'TE (ms)';
            app.TEViewField = uieditfield(app.ScanparametersPanel, 'numeric');
            app.TEViewField.Editable = 'off';
            app.NALabel = uilabel(app.ScanparametersPanel);
            app.NALabel.Text = 'NA';
            app.NAViewField = uieditfield(app.ScanparametersPanel, 'numeric');
            app.NAViewField.Editable = 'off';
            app.FALabel = uilabel(app.ScanparametersPanel);
            app.FALabel.Text = 'FA';
            app.FAViewField = uieditfield(app.ScanparametersPanel, 'numeric');
            app.FAViewField.Editable = 'off';
            app.SequenceEditFieldLabel = uilabel(app.ScanparametersPanel);
            app.SequenceEditFieldLabel.Text = 'Sequence';
            app.SequenceViewField = uieditfield(app.ScanparametersPanel, 'text');
            app.SequenceViewField.Editable = 'off';
            app.MRDfileLabel = uilabel(app.ScanparametersPanel);
            app.MRDfileLabel.Text = 'MRD file';
            app.MRDfileViewField = uieditfield(app.ScanparametersPanel, 'text');
            app.MRDfileViewField.Editable = 'off';
            app.VenccmsLabel = uilabel(app.ScanparametersPanel);
            app.VenccmsLabel.Text = 'Venc (cm/s)';
            app.VencViewField = uieditfield(app.ScanparametersPanel, 'text');
            app.VencViewField.Editable = 'off';
            app.MessagesPanel = uipanel(app.FlowUIFigure);
            app.MessagesPanel.Title = 'Messages';
            app.MessageBox = uitextarea(app.MessagesPanel);
            app.VelocityImagesPanel = uipanel(app.FlowUIFigure);
            app.VelocityImagesPanel.Title = 'Velocity Images';
            app.RecoFig = uiaxes(app.VelocityImagesPanel);
            title(app.RecoFig, '')
            xlabel(app.RecoFig, '')
            ylabel(app.RecoFig, '')
            app.RecoFig.XTick = [];
            app.RecoFig.YTick = [];
            app.Dim3Slider = uislider(app.VelocityImagesPanel);
            app.Dim3Slider.Limits = [1 200];
            app.Dim3Slider.MajorTicks = [];
            app.Dim3Slider.ValueChangedFcn = createCallbackFcn(app, @Dim3SliderValueChanged, true);
            app.Dim3Slider.MinorTicks = [];
            app.Dim3Slider.Value = 1;
            app.Dim3EditField = uieditfield(app.VelocityImagesPanel, 'numeric');
            app.Dim3EditField.Limits = [1 Inf];
            app.Dim3EditField.ValueDisplayFormat = '%.0f';
            app.Dim3EditField.ValueChangedFcn = createCallbackFcn(app, @Dim3EditFieldValueChanged, true);
            app.Dim3EditField.Value = 1;
            app.XLabel = uilabel(app.VelocityImagesPanel);
            app.XLabel.Text = '     X     ';
            app.YLabel = uilabel(app.VelocityImagesPanel);
            app.YLabel.Text = '     Y     ';
            app.Dim3Label = uilabel(app.VelocityImagesPanel);
            app.Dim3Label.Text = 'Slice';
            app.LevelSlider = uislider(app.VelocityImagesPanel);
            app.LevelSlider.Limits = [-10000 10000];
            app.LevelSlider.MajorTicks = [];
            app.LevelSlider.ValueChangedFcn = createCallbackFcn(app, @LevelSliderValueChanged, true);
            app.LevelSlider.MinorTicks = [];
            app.WindowLabel = uilabel(app.VelocityImagesPanel);
            app.WindowLabel.Text = 'Window';
            app.LevelLabel = uilabel(app.VelocityImagesPanel);
            app.LevelLabel.Text = 'Level';
            app.WindowSlider = uislider(app.VelocityImagesPanel);
            app.WindowSlider.Limits = [0 10000];
            app.WindowSlider.MajorTicks = [];
            app.WindowSlider.ValueChangedFcn = createCallbackFcn(app, @WindowSliderValueChanged, true);
            app.WindowSlider.MinorTicks = [];
            app.WindowSlider.Value = 1;
            app.LevelEditField = uieditfield(app.VelocityImagesPanel, 'numeric');
            app.LevelEditField.ValueChangedFcn = createCallbackFcn(app, @LevelEditFieldValueChanged, true);
            app.WindowEditField = uieditfield(app.VelocityImagesPanel, 'numeric');
            app.WindowEditField.ValueChangedFcn = createCallbackFcn(app, @WindowEditFieldValueChanged, true);
            app.AutoButton = uibutton(app.VelocityImagesPanel, 'push');
            app.AutoButton.ButtonPushedFcn = createCallbackFcn(app, @AutoButtonPushed, true);
            app.AutoButton.Text = 'Auto';
            app.OrientationSpinnerLabel_2 = uilabel(app.VelocityImagesPanel);
            app.OrientationSpinnerLabel_2.Text = 'Orientation';
            app.ImageOrientationSpinner = uispinner(app.VelocityImagesPanel);
            app.ImageOrientationSpinner.Limits = [1 3];
            app.ImageOrientationSpinner.ValueChangedFcn = createCallbackFcn(app, @ImageOrientationSpinnerValueChanged, true);
            app.ImageOrientationSpinner.Value = 1;
            app.ImageDropDownLabel = uilabel(app.VelocityImagesPanel);
            app.ImageDropDownLabel.Text = 'Image';
            app.ImageDropDown = uidropdown(app.VelocityImagesPanel);
            app.ImageDropDown.Items = {'Vx (cm/s)', 'Vy (cm/s)', 'Vz (cm/s)', '|V| (cm/s)', 'Modulus', 'Difference'};
            app.ImageDropDown.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.ImageDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageDropDownValueChanged, true);
            app.ImageDropDown.Value = '3';
            app.HorFig = uiaxes(app.VelocityImagesPanel);
            title(app.HorFig, 'horizontal')
            xlabel(app.HorFig, '')
            ylabel(app.HorFig, '')
            app.VerFig = uiaxes(app.VelocityImagesPanel);
            title(app.VerFig, 'vertical')
            xlabel(app.VerFig, '')
            ylabel(app.VerFig, '')
            app.Image = uiimage(app.VelocityImagesPanel);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.TitleLabel = uilabel(app.FlowUIFigure);
            app.TitleLabel.Text = 'MR Solutions Flow';
            app.TulpImage = uiimage(app.FlowUIFigure);
            app.CreditsLabel = uilabel(app.FlowUIFigure);
            app.CreditsLabel.Text = 'Gustav Strijkers, Amsterdam UMC, Version 1.0';
            % Show the figure after all components are created
            app.FlowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MRSflow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FlowUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FlowUIFigure)
        end
    end
end